classdef HelicopterParameterEstimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelicopterParameterEstimationUIFigure  matlab.ui.Figure
        TailbladenumberEditField       matlab.ui.control.NumericEditField
        TailbladenumberEditFieldLabel  matlab.ui.control.Label
        CalculateParametersButton      matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        MainbladenumberEditField       matlab.ui.control.NumericEditField
        MainbladenumberEditFieldLabel  matlab.ui.control.Label
        MTOWEditField                  matlab.ui.control.NumericEditField
        MTOWEditFieldLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateParametersButton, UITable
        function CalculateButtonPushed(app, event)
%% Main Rotor
D = 0.977 * app.MTOWEditField.Value^(0.308); % Diameter
R = D/2;                    % Radius
c = .0619*app.MTOWEditField.Value^(.226)/...
    app.MainbladenumberEditField.Value^(.426); % Chord
AngVel = 57.967*D^(0.6149)/R;

%% Tail Rotor
Dtr = 0.0886 * app.MTOWEditField.Value^(0.393);  % Diameter
Rtr = Dtr/2;                % Radius
AngVeltr = 57.967*Dtr^(0.6149)/Rtr;


%% Total Power
Pto = 0.2928 * app.MTOWEditField.Value^(0.9043); % Total power at GTOW

%% Airframe
Lf = 0.824*D^(1.056);   % Fuselage length
Lrt = 1.09*D^(1.03);    % Overall length

%% Weight Prediction
We = 0.59*app.MTOWEditField.Value;
Wpl = 0.31*app.MTOWEditField.Value;

%% Table
app.UITable.Data = ...
   {'Maximum Take-off Weight (kg)', sprintf('%.1f', app.MTOWEditField.Value);
    'Main Blade Number', sprintf('%.0f', app.MainbladenumberEditField.Value);
    'Tail Blade Number', sprintf('%.0f', app.TailbladenumberEditField.Value);
    'Empty Weight (kg)', sprintf('%.1f', We);
    'Payload (kg)', sprintf('%.1f', Wpl);
    'Main Rotor Radius (m)', sprintf('%.2f', R);
    'Main Rotor Chord (m)', sprintf('%.2f', c);
    'Main Rotor Angular Velocity (1/s)', sprintf('%.0f', AngVel);
    'Tail Rotor Radius (m)', sprintf('%.2f', Rtr);
    'Tail Rotor Angular Velocity (1/s)', sprintf('%.0f', AngVeltr);
    'Takeoff Total Power (kW)', sprintf('%.2f', Pto);
    'Fuselage Length (m)', sprintf('%.2f', Lf);
    'Airframe Length (m)', sprintf('%.2f', Lrt)};

%% ASSIGN-IN
assignin('base', 'MTOW', app.MTOWEditField.Value);
assignin('base', 'Nb', app.MainbladenumberEditField.Value);
assignin('base', 'Nbtr', app.TailbladenumberEditField.Value);
assignin('base', 'We', We);
assignin('base', 'Wpl', Wpl);
assignin('base', 'R', R);
assignin('base', 'c', c);
assignin('base', 'AngVel', AngVel);
assignin('base', 'Rtr', Rtr);
assignin('base', 'AngVeltr', AngVeltr);
assignin('base', 'Pto', Pto);
assignin('base', 'Lf', Lf);
assignin('base', 'Lrt', Lrt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelicopterParameterEstimationUIFigure = uifigure('Visible', 'off');
            app.HelicopterParameterEstimationUIFigure.Name = 'Helicopter Parameter Estimation';
            app.MTOWEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.MTOWEditFieldLabel.Text = 'MTOW';
            app.MTOWEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.MTOWEditField.Value = 100;
            app.MainbladenumberEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.MainbladenumberEditFieldLabel.Text = 'Main blade number';
            app.MainbladenumberEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.MainbladenumberEditField.Value = 2;
            app.UITable = uitable(app.HelicopterParameterEstimationUIFigure);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateParametersButton = uibutton(app.HelicopterParameterEstimationUIFigure, 'push');
            app.CalculateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateParametersButton.Text = 'Calculate Parameters';
            app.TailbladenumberEditFieldLabel = uilabel(app.HelicopterParameterEstimationUIFigure);
            app.TailbladenumberEditFieldLabel.Text = 'Tail blade number';
            app.TailbladenumberEditField = uieditfield(app.HelicopterParameterEstimationUIFigure, 'numeric');
            app.TailbladenumberEditField.Value = 2;
            % Show the figure after all components are created
            app.HelicopterParameterEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelicopterParameterEstimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelicopterParameterEstimationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelicopterParameterEstimationUIFigure)
        end
    end
end